classdef Hill_muscle_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        OptimalFibreLengthmmEditFieldLabel  matlab.ui.control.Label
        OptimalFibreLengthEditField    matlab.ui.control.NumericEditField
        InitialFibreLengthLoEditFieldLabel  matlab.ui.control.Label
        InitialFibreLengthLoEditField  matlab.ui.control.NumericEditField
        SeriesElasticElementLengthmmEditFieldLabel  matlab.ui.control.Label
        SeriesElasticElementLengthEditField  matlab.ui.control.NumericEditField
        SimulateContractionButton      matlab.ui.control.Button
        VmaxLosEditFieldLabel          matlab.ui.control.Label
        VmaxLosEditField               matlab.ui.control.NumericEditField
        Tau1EditFieldLabel             matlab.ui.control.Label
        Tau1EditField                  matlab.ui.control.NumericEditField
        Tau2EditFieldLabel             matlab.ui.control.Label
        Tau2EditField                  matlab.ui.control.NumericEditField
        pCa50EditFieldLabel            matlab.ui.control.Label
        pCa50EditField                 matlab.ui.control.NumericEditField
        nHEditFieldLabel               matlab.ui.control.Label
        nHEditField                    matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel     matlab.ui.control.Label
        PulseWidthmsEditField          matlab.ui.control.NumericEditField
        NumberofpulsesKnobLabel        matlab.ui.control.Label
        NumberofpulsesKnob             matlab.ui.control.Knob
        NumberofpulsesEditField        matlab.ui.control.NumericEditField
        StimulationfrequencyKnobLabel  matlab.ui.control.Label
        StimulationfrequencyKnob       matlab.ui.control.Knob
        StimulationfrequencyEditField  matlab.ui.control.NumericEditField
        HoldplotSwitchLabel            matlab.ui.control.Label
        HoldplotSwitch                 matlab.ui.control.RockerSwitch
        ClearplotButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        ActivationAxes                 matlab.ui.control.UIAxes
        ForceAxes                      matlab.ui.control.UIAxes
        LengthAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateContractionButton
        function SimulateContractionButtonPushed(app, event)
            % Define initial length or lengths of CC
            prop.Lfd = [app.InitialFibreLengthLoEditField.Value]-1;
            % define optimal fascicle length
            prop.L0 = app.OptimalFibreLengthEditField.Value;
            % define series elastic (tendon) length
            prop.Lsee = app.SeriesElasticElementLengthEditField.Value;
            % calculate muscle length (mm)
            ML = (prop.Lfd+1)*prop.L0;
            % calculate the tendon-length ration relative to normal of 6
            % (30% FEC)
            prop.NewTL = (prop.Lsee/prop.L0)/6;
            
            %% DEFINE PARAMETER VARIABLES IN MODEL BLOCKS
            % Time constant for Ca2+ release
            prop.TAU1 = app.Tau1EditField.Value; % 100% = 0.0422; 70% rate = 0.06028; 50% rate = 0.0844 = 100% time constant
            % Time constant for Ca2+ removal
            prop.TAU2 = app.Tau2EditField.Value; % 100% = 0.256; 70% rate = 0.3657 = 43% time constant; 50% rate = 0.5120 = 100% time constant
            % Maximum velocity of shortening
            prop.VMAX = app.VmaxLosEditField.Value;
            % Ca2+ sensitivity of force
            prop.PCA50 = app.pCa50EditField.Value; % 0.1025; 0.115 (-0.05 pCa); 0.129 (-0.1 pCa)
            % Hill coefficient
            prop.NH = app.nHEditField.Value;
            
            % assign the properties of the model to the base workspace
            assignin("base","prop",prop);
            
            % define stimulation parameters
            freq = app.StimulationfrequencyEditField.Value;
            % Interstimulus interval - 0 included for twitch
            ISI = 1/freq;
            %pulses = app.NumberofStimulationPulsesEditField.Value;
            pulses = app.NumberofpulsesKnob.Value;
            
            % Calculate simulation time as time of stims + 0.4 seconds 
            duration = (pulses * ISI) + 0.8;
            % Delay from start of stimulation
            delay = 0.0001;%sec     
            
            % Pulse width (convert from ms to seconds)
            pulse_width1 = app.PulseWidthmsEditField.Value/1000;
            
            % True ISI after counting for duation of pulse width
            ISI = ISI - pulse_width1;
            
            if ISI > 0

                %DETERMINE INACTIVATION OF CA2+ RELEASE FOR SUBSEQUENT PULSES - BARCLAY, 2012
                %A = max Ca2+ deactivation
                %t = ISI
                %tau = rate constant of recovery
                % y = (1 - A)*exp(ISI/tau);
                %y = 1 - A*exp(-ISI/tau);
                pulse_width2 = pulse_width1 - (pulse_width1*((1 - 0.2)*exp((-ISI)*1000/350)));
            else
                pulse_width2 = 0;
            end
            
            pulse_widths =  [pulse_width1 ,repmat(pulse_width2,1,pulses-1,1)];

            % Smapling frequency
            fs = 10000;
            delay = delay *fs;%frames
            time = (0:1/fs:duration)'; %frames
            pulse_width = round(pulse_widths * fs);%frames
            pulse_isi = ISI* fs;%frames

            %% CREATE STIMULATION INPUT SIGNAL %%

            % Could probably be done more eloquently, but it works

            % Set stimulation duration
            stim_output = zeros(length(time),1);

            if pulses > 1
                n = 1:pulses;
                a = pulse_width+pulse_isi;
                j = 1:(pulse_width(1,1)+pulse_isi);
                for p = 1:pulses-1 %%%%

                    pulse_isi = round(pulse_isi);
                    
                    % if statement to account for very small discrepancy in sample number arising from
                    % certain stimulation frequencies (e.g. 15, 30, 70 vs 10,
                    % 20, 40, 50 100 etc)
                  
                    if length(j(1,1) : j(1,end)) < length(vertcat(ones(pulse_width(1,p),1), (zeros(pulse_isi,1))))
                        
                        gap = length(vertcat(ones(pulse_width(1,p),1), (zeros(pulse_isi,1)))) - length(j(1,1) : j(1,end));
                        
                        stim_pulses (1,j(1,1) : j(1,end)) = vertcat(ones(pulse_width(1,p),1), (zeros(pulse_isi-gap,1)));
                        
                    elseif length(j(1,1) : j(1,end)) == length(vertcat(ones(pulse_width(1,p),1), (zeros(pulse_isi,1))))
                        
                        stim_pulses (1,j(1,1) : j(1,end)) = vertcat(ones(pulse_width(1,p),1), (zeros(pulse_isi,1)));
                        
                    end
                    
                    %stim_pulses (1,j(1,1) : j(1,end)) = vertcat(ones(pulse_width(1,p),1), (zeros(pulse_isi,1)));

                    b = j(1,1);
                    if p == 1
                        c = j(1,end-(pulse_width(1)-pulse_width(2)));
                        j = (b+a): (c+a);
                    else
                        c = j(1,end);
                        j = (b+a): (c+a);
                    end
                end
                if pulses == 2
                    stim_pulses_all = vertcat(stim_pulses',ones(pulse_width(1,2),1));

                elseif pulses > 2
                    stim_pulses_all = vertcat(stim_pulses',zeros(pulse_width(1,1)-pulse_width(1,end),1),ones(pulse_width(1,2),1));

                end
                stim_pulses_delay = vertcat(zeros(delay,1),stim_pulses_all);
                stim_output(1:length(stim_pulses_delay)) = stim_pulses_delay;
            else
                stim(delay+1:delay+pulse_width) = 1;
                stim_output(1:length(stim)) = stim';
            end

            %% INPUT FOR SIMULINK
            data_in = [time stim_output];
            assignin("base","data_in",data_in);
            assignin("base","Duration",duration)
            
            %% DEFINE MUSCLE MODEL TO RUN
            % Run specified model
            SimOut = sim('Hill_muscle_model_simulink.slx','StopTime', num2str(duration));

            % New sampling rate - 10000 Hz (fs) to 2000 Hz (ns)
            ns = 2000;
            % Downsample data from 10kHz to 2kHz
            ni = fs/ns;

            % Downsample data through interpolation because of random number of
            % samples/duration from simulation
            sim_time = SimOut.tout;
            new_time = linspace(sim_time(1),sim_time(end),length(time));

            % Force - Total
            Force.Total= interp1(sim_time,SimOut.Out_CCforce.signals.values,new_time);
            % Stimulation
            Stimulation= downsample(SimOut.Out_Stimulation.signals.values,ni);
            % Activation Level
            Activation = interp1(sim_time,SimOut.Out_Activation.signals.values,new_time);
            % Activator Concentration
            Activator = interp1(sim_time,SimOut.Out_Activator.signals.values,new_time);
            % CC Length
            Length.CC= interp1(sim_time,SimOut.Out_CClength.signals.values,new_time);
            % PEC Force
            Force.Passive = interp1(sim_time,SimOut.Out_PECforce.signals.values,new_time);
            % MTU Length
            Length.MTU = interp1(sim_time,SimOut.Out_MTUlength.signals.values,new_time);
            % SEE Length
            Length.SEE = interp1(sim_time,SimOut.Out_SEElength.signals.values,new_time);
            
            % Hold plot switch
            if strcmp(app.HoldplotSwitch.Value,'Off') == 1
                  hold(app.ForceAxes, 'off')
                  hold(app.LengthAxes, 'off')
            else
                hold(app.ForceAxes, 'on')
                hold(app.LengthAxes, 'on')
            end
            
            %plot data
            plot(app.ActivationAxes,new_time',[Activator' Activation'],'LineWidth',2);
            ylim(app.ActivationAxes,[0 1]);
            legend(app.ActivationAxes,{'Activator','Acivation'},'Location','North East')
            plot(app.ForceAxes,new_time',[Force.Total' Force.Passive'],'LineWidth',2);
            ylim(app.ForceAxes,[0 1]);
            legend(app.ForceAxes,{'Total force','Passive force'},'Location','North East')
            %yyaxis(app.LengthAxes, 'right');
            plot(app.LengthAxes,new_time',[Length.CC'+1 ],'LineWidth',2);
            legend(app.LengthAxes,{'CE length'},'Location','North East')
            ylim(app.LengthAxes,[0.6 1.4]);

        end

        % Callback function
        function NumberofStimulationPulsesSliderValueChanged(app, event)
            value = round(app.NumberofStimulationPulsesSlider.Value);
            app.NumberofStimulationPulsesSlider.Value = value;
            app.NumberofStimulationPulsesEditField.Value = value;
        end

        % Callback function
        function NumberofStimulationPulsesEditFieldValueChanged(app, event)
            value = round(app.NumberofStimulationPulsesEditField.Value);
            app.NumberofStimulationPulsesEditField.Value = value;
            app.NumberofStimulationPulsesSlider.Value = value;
        end

        % Value changed function: NumberofpulsesKnob
        function NumberofpulsesKnobValueChanged(app, event)
            value = round(app.NumberofpulsesKnob.Value);
            app.NumberofpulsesKnob.Value = value;
            app.NumberofpulsesEditField.Value = value;
        end

        % Value changed function: NumberofpulsesEditField
        function NumberofpulsesEditFieldValueChanged(app, event)
            value = round(app.NumberofpulsesEditField.Value);
            app.NumberofpulsesEditField.Value = value;
            app.NumberofpulsesKnob.Value = value;
        end

        % Value changed function: StimulationfrequencyKnob
        function StimulationfrequencyKnobValueChanged(app, event)
            value = round(app.StimulationfrequencyKnob.Value);
            app.StimulationfrequencyKnob.Value = value;
            app.StimulationfrequencyEditField.Value = value;
        end

        % Value changed function: StimulationfrequencyEditField
        function StimulationfrequencyEditFieldValueChanged(app, event)
            value = app.StimulationfrequencyEditField.Value;
            app.StimulationfrequencyEditField.Value = value;
            app.StimulationfrequencyKnob.Value = value;
        end

        % Value changed function: HoldplotSwitch
        function HoldplotSwitchValueChanged(app, event)
            value = app.HoldplotSwitch.Value;
        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            cla(app.ActivationAxes)    
            cla(app.ForceAxes)
            cla(app.LengthAxes)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {681, 681};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {487, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OptimalFibreLengthmmEditFieldLabel = uilabel(app.LeftPanel);
            app.OptimalFibreLengthmmEditFieldLabel.Text = 'Optimal Fibre Length (mm)';
            app.OptimalFibreLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OptimalFibreLengthEditField.Value = 50;
            app.InitialFibreLengthLoEditFieldLabel = uilabel(app.LeftPanel);
            app.InitialFibreLengthLoEditFieldLabel.Text = 'Initial Fibre Length (Lo)';
            app.InitialFibreLengthLoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InitialFibreLengthLoEditField.Value = 1.2;
            app.SeriesElasticElementLengthmmEditFieldLabel = uilabel(app.LeftPanel);
            app.SeriesElasticElementLengthmmEditFieldLabel.Text = 'Series Elastic Element Length (mm)';
            app.SeriesElasticElementLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SeriesElasticElementLengthEditField.Value = 300;
            app.SimulateContractionButton = uibutton(app.LeftPanel, 'push');
            app.SimulateContractionButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateContractionButtonPushed, true);
            app.SimulateContractionButton.Text = 'Simulate Contraction';
            app.VmaxLosEditFieldLabel = uilabel(app.LeftPanel);
            app.VmaxLosEditFieldLabel.Text = 'Vmax (Lo/s)';
            app.VmaxLosEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VmaxLosEditField.Limits = [1 25];
            app.VmaxLosEditField.Value = 6;
            app.Tau1EditFieldLabel = uilabel(app.LeftPanel);
            app.Tau1EditFieldLabel.Text = 'Tau1';
            app.Tau1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Tau1EditField.Limits = [0.005 0.99];
            app.Tau1EditField.Value = 0.0422;
            app.Tau2EditFieldLabel = uilabel(app.LeftPanel);
            app.Tau2EditFieldLabel.Text = 'Tau2';
            app.Tau2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Tau2EditField.Limits = [0.005 0.99];
            app.Tau2EditField.Value = 0.256;
            app.pCa50EditFieldLabel = uilabel(app.LeftPanel);
            app.pCa50EditFieldLabel.Text = 'pCa50';
            app.pCa50EditField = uieditfield(app.LeftPanel, 'numeric');
            app.pCa50EditField.Limits = [0.01 0.6];
            app.pCa50EditField.Value = 0.1025;
            app.nHEditFieldLabel = uilabel(app.LeftPanel);
            app.nHEditFieldLabel.Text = 'nH';
            app.nHEditField = uieditfield(app.LeftPanel, 'numeric');
            app.nHEditField.Limits = [0.5 10];
            app.nHEditField.Value = 3;
            app.PulseWidthmsEditFieldLabel = uilabel(app.LeftPanel);
            app.PulseWidthmsEditFieldLabel.Text = 'Pulse Width (ms)';
            app.PulseWidthmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PulseWidthmsEditField.Limits = [0.5 20];
            app.PulseWidthmsEditField.Value = 4.8;
            app.NumberofpulsesKnobLabel = uilabel(app.LeftPanel);
            app.NumberofpulsesKnobLabel.Text = 'Number of pulses';
            app.NumberofpulsesKnob = uiknob(app.LeftPanel, 'continuous');
            app.NumberofpulsesKnob.Limits = [1 100];
            app.NumberofpulsesKnob.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.NumberofpulsesKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofpulsesKnobValueChanged, true);
            app.NumberofpulsesKnob.MinorTicks = [5 15 25 35 45 55 65 75 85 95];
            app.NumberofpulsesKnob.Value = 1;
            app.NumberofpulsesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofpulsesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpulsesEditFieldValueChanged, true);
            app.NumberofpulsesEditField.Value = 1;
            app.StimulationfrequencyKnobLabel = uilabel(app.LeftPanel);
            app.StimulationfrequencyKnobLabel.Text = 'Stimulation frequency';
            app.StimulationfrequencyKnob = uiknob(app.LeftPanel, 'continuous');
            app.StimulationfrequencyKnob.Limits = [1 200];
            app.StimulationfrequencyKnob.MajorTicks = [1 25 50 75 100 125 150 175 200];
            app.StimulationfrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @StimulationfrequencyKnobValueChanged, true);
            app.StimulationfrequencyKnob.Value = 1;
            app.StimulationfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StimulationfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationfrequencyEditFieldValueChanged, true);
            app.StimulationfrequencyEditField.Value = 1;
            app.HoldplotSwitchLabel = uilabel(app.LeftPanel);
            app.HoldplotSwitchLabel.Text = 'Hold plot';
            app.HoldplotSwitch = uiswitch(app.LeftPanel, 'rocker');
            app.HoldplotSwitch.ValueChangedFcn = createCallbackFcn(app, @HoldplotSwitchValueChanged, true);
            app.ClearplotButton = uibutton(app.LeftPanel, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearplotButton.Text = 'Clear plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.ActivationAxes = uiaxes(app.RightPanel);
            title(app.ActivationAxes, 'Activator / Activation')
            xlabel(app.ActivationAxes, 'Time (s)')
            ylabel(app.ActivationAxes, 'Activator / Activation')
            zlabel(app.ActivationAxes, 'Z')
            app.ForceAxes = uiaxes(app.RightPanel);
            title(app.ForceAxes, 'Active / Passive force')
            xlabel(app.ForceAxes, 'Time (s)')
            ylabel(app.ForceAxes, 'Force ({\itP}/{\itP}_{0})')
            zlabel(app.ForceAxes, 'Z')
            app.LengthAxes = uiaxes(app.RightPanel);
            title(app.LengthAxes, 'CE length')
            xlabel(app.LengthAxes, 'Time (s)')
            ylabel(app.LengthAxes, 'Length ({\itL}/{\itL}_{0})')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hill_muscle_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end